classdef TG2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        CurrentConditionTab  matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        UITable_2            matlab.ui.control.Table
        TGconditionButton    matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        PredictionTab        matlab.ui.container.Tab
        TGpredictionButton   matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        SchedulingTab        matlab.ui.container.Tab
        HelpTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: CurrentConditionTab
        function CurrentConditionTabSizeChanged(app, event)
            position = app.CurrentConditionTab.Position;
            
        end

        % Button pushed function: TGconditionButton
        function TGconditionButtonPushed(app, event)
            t = readtable("Sample.xlsx","sheet",1);
            app.UITable.Data = t;
            t.Properties.VariableNames{1}='Position';
            t.Properties.VariableNames{2}='LL';
            app.UITable.ColumnName = t.Properties.VariableNames;
            x= table2array(t(:,"Position"));
            y= table2array(t(:,"LL"));
            plot(app.UIAxes,x,y,"LineWidth",2);
            t2 = readtable("Sample.xlsx","sheet",2);
            app.UITable_2.Data = t2;
            t2.Properties.VariableNames{1}='Position';
            t2.Properties.VariableNames{2}='LL';
            app.UITable_2.ColumnName = t2.Properties.VariableNames;
            x1= table2array(t2(:,"Position"));
            y1= table2array(t2(:,"LL"));
            plot(app.UIAxes_2,x1,y1,"LineWidth",2);
        end

        % Button pushed function: TGpredictionButton
        function TGpredictionButtonPushed(app, event)
            %% Time
SDH=[0.755911605	0.760538268	0.778960595	0.8	0.94	1.5	1.833701052	2.080565769	2.419585706];
peak=[-2.069999933	-2.140000105	-2.140000105	-2.650000095	-3.499999952	-6.96999979	-9.449999809	-10.800000191	-11.61999989];
Time=[3	43	106	155	271	354	432	491	566];
Time=Time/365;
%            plot(app.UIAxes2,Time,SDH,"LineWidth",2);
           plot(app.UIAxes2,Time,SDH,'o--','MarkerSize',25,'linewidth',3,'MarkerEdgeColor','k'); 
           plot(app.UIAxes3,Time,peak,'o--','MarkerSize',25,'linewidth',3,'MarkerEdgeColor','k');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurrentConditionTab = uitab(app.TabGroup);
            app.CurrentConditionTab.SizeChangedFcn = createCallbackFcn(app, @CurrentConditionTabSizeChanged, true);
            app.CurrentConditionTab.Title = 'Current Condition';
            app.UITable = uitable(app.CurrentConditionTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.CurrentConditionTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable_2.RowName = {};
            app.TGconditionButton = uibutton(app.CurrentConditionTab, 'push');
            app.TGconditionButton.ButtonPushedFcn = createCallbackFcn(app, @TGconditionButtonPushed, true);
            app.TGconditionButton.Text = 'TG condition';
            app.UIAxes = uiaxes(app.CurrentConditionTab);
            title(app.UIAxes, 'Track section1')
            xlabel(app.UIAxes, 'Position (m)')
            ylabel(app.UIAxes, 'Longitudinal level (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.CurrentConditionTab);
            title(app.UIAxes_2, 'Track section 2')
            xlabel(app.UIAxes_2, 'Position (m)')
            ylabel(app.UIAxes_2, 'Longitudinal level (mm)')
            zlabel(app.UIAxes_2, 'Z')
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.TGpredictionButton = uibutton(app.PredictionTab, 'push');
            app.TGpredictionButton.ButtonPushedFcn = createCallbackFcn(app, @TGpredictionButtonPushed, true);
            app.TGpredictionButton.Text = 'TG prediction';
            app.UIAxes2 = uiaxes(app.PredictionTab);
            title(app.UIAxes2, 'Aggregated prediction')
            xlabel(app.UIAxes2, 'Time (year)')
            ylabel(app.UIAxes2, 'Standard deviation of longitudinal level (mm)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PredictionTab);
            title(app.UIAxes3, 'Detailed prediction')
            xlabel(app.UIAxes3, 'Time (year)')
            ylabel(app.UIAxes3, 'Amplitude of longitudinal level (mm)')
            zlabel(app.UIAxes3, 'Z')
            app.SchedulingTab = uitab(app.TabGroup);
            app.SchedulingTab.Title = 'Scheduling';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TG2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end